classdef StaticKinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StaticKinetUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        BloomLabWebsiteMenu            matlab.ui.container.Menu
        OtherProgramsMenu              matlab.ui.container.Menu
        GitHubMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        BaseStructurePanel             matlab.ui.container.Panel
        RotationButtonGroup            matlab.ui.container.ButtonGroup
        FixedRotationButton            matlab.ui.control.RadioButton
        RandomRotationButton           matlab.ui.control.RadioButton
        RotationCheckBox               matlab.ui.control.CheckBox
        RotationParametersdegPanel     matlab.ui.container.Panel
        XSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        ZSpinner                       matlab.ui.control.Spinner
        LowLimSpinnerLabel             matlab.ui.control.Label
        LowLimSpinner                  matlab.ui.control.Spinner
        UpLimSpinnerLabel              matlab.ui.control.Label
        UpLimSpinner                   matlab.ui.control.Spinner
        TubuleDiameternmSpinnerLabel   matlab.ui.control.Label
        StructureDiameternmSpinnerLabel  matlab.ui.control.Label
        NumberofFluorophoreSpinnerLabel  matlab.ui.control.Label
        ColorChannelDropDownLabel      matlab.ui.control.Label
        NumberofChromosomesSpinnerLabel  matlab.ui.control.Label
        LengthnmSpinnerLabel           matlab.ui.control.Label
        LengthnmSpinner                matlab.ui.control.Spinner
        TubuleDiameternmSpinner        matlab.ui.control.Spinner
        StructureDiameternmSpinner     matlab.ui.control.Spinner
        NumberofFluorophoreSpinner     matlab.ui.control.Spinner
        ColorChannelDropDown           matlab.ui.control.DropDown
        NumberofChromosomesSpinner     matlab.ui.control.Spinner
        StaggerDropDownLabel           matlab.ui.control.Label
        StaggerDropDown                matlab.ui.control.DropDown
        RangenmSpinnerLabel            matlab.ui.control.Label
        RangenmSpinner                 matlab.ui.control.Spinner
        NumberofComplexesSwitchLabel   matlab.ui.control.Label
        NumberofComplexesSwitch        matlab.ui.control.Switch
        BaseStructureCheckBox          matlab.ui.control.CheckBox
        OutputPanel                    matlab.ui.container.Panel
        FileFolderNameStringLabel      matlab.ui.control.Label
        FileFolderNameStringEditField  matlab.ui.control.EditField
        GenerateXMLCheckBox            matlab.ui.control.CheckBox
        SaveMATFileCheckBox            matlab.ui.control.CheckBox
        GenerateTIFsCheckBox           matlab.ui.control.CheckBox
        GenerateOutputButton           matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        DViewPopOutButton              matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        AxisLimitsButtonGroup          matlab.ui.container.ButtonGroup
        AutomaticButton                matlab.ui.control.RadioButton
        FixedButton                    matlab.ui.control.RadioButton
        YLowerLimnmEditFieldLabel      matlab.ui.control.Label
        YLowerLimnmEditField1          matlab.ui.control.NumericEditField
        YUpperLimnmEditFieldLabel      matlab.ui.control.Label
        YUpperLimnmEditField1          matlab.ui.control.NumericEditField
        XLowerLimnmEditFieldLabel      matlab.ui.control.Label
        XLowerLimnmEditField1          matlab.ui.control.NumericEditField
        XUpperLimnmEditFieldLabel      matlab.ui.control.Label
        XUpperLimnmEditField1          matlab.ui.control.NumericEditField
        YLowerLimnmEditField_2Label    matlab.ui.control.Label
        YLowerLimnmEditField2          matlab.ui.control.NumericEditField
        YUpperLimnmEditField_2Label    matlab.ui.control.Label
        YUpperLimnmEditField2          matlab.ui.control.NumericEditField
        XLowerLimnmEditField_2Label    matlab.ui.control.Label
        XLowerLimnmEditField2          matlab.ui.control.NumericEditField
        XUpperLimnmEditField_2Label    matlab.ui.control.Label
        XUpperLimnmEditField2          matlab.ui.control.NumericEditField
        YLowerLimnmEditField_3Label    matlab.ui.control.Label
        YLowerLimnmEditField3          matlab.ui.control.NumericEditField
        YUpperLimnmEditField_3Label    matlab.ui.control.Label
        YUpperLimnmEditField3          matlab.ui.control.NumericEditField
        XLowerLimnmEditField_3Label    matlab.ui.control.Label
        XLowerLimnmEditField3          matlab.ui.control.NumericEditField
        XUpperLimnmEditField_3Label    matlab.ui.control.Label
        XUpperLimnmEditField3          matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        BasePointInfo % Contains information about the points that make up the base structure
        MasterPointInfo % Contains information about the points that make up the entire simulation
        LengthSim % Array of possible stagger lengths acquired from running Coupled Model Crosslinked Simulation by Stevens et al.
        BioDistances % Description
    end

    properties (Access = private)
    end

    methods (Access = public)
    
        % Function that produces fluorophore location for a single cylinder for the base model
        function CylinderCoordPoints = CylinderFluorophorePoints(app)
            CylinderCoordPoints = zeros(3,app.NumberofFluorophoreSpinner.Value);
            for PointIdx = 1:app.NumberofFluorophoreSpinner.Value
                CylinderCoordPoints(1,PointIdx) = app.LengthnmSpinner.Value*(rand()-0.5);
                CoordAng = rand()*2*pi;
                CylinderCoordPoints(2,PointIdx) = cos(CoordAng)*app.TubuleDiameternmSpinner.Value/2;
                CylinderCoordPoints(3,PointIdx) = sin(CoordAng)*app.TubuleDiameternmSpinner.Value/2;
            end
            if app.RotationCheckBox.Enable == 'on' & app.RotationCheckBox.Value == true % Allow for rotation of the initial coordinates
                if app.FixedRotationButton.Enable == 'on' & app.FixedRotationButton.Value == true % Rotation that is fixed for each axis
                    CylinderCoordPoints = rotx(app.XSpinner.Value)*roty(app.YSpinner.Value)*rotz(app.ZSpinner.Value)*CylinderCoordPoints;
                elseif app.RandomRotationButton.Enable == 'on' & app.RandomRotationButton.Value == true % Rotation that is random, but confined to a certain range of possible angles 
                    randanglediff = app.UpLimSpinner.Value - app.LowLimSpinner.Value;
                    CylinderCoordPoints = roty(rand()*randanglediff+app.LowLimSpinner.Value)*rotz(rand()*randanglediff+app.LowLimSpinner.Value)*CylinderCoordPoints;
                end
            end
        end
        
        function BaseBuilder(app)
            app.BasePointInfo.CoordPoints = [];
            if strcmp(app.NumberofComplexesSwitch.Value, '1')
                BaseStructure1(app)
            else
                app.BasePointInfo.CoordPoints2 = [];
                BaseStructure2(app)
            end
            app.BasePointInfo.Color = repmat(convertCharsToStrings(app.ColorChannelDropDown.Value),1,size(app.BasePointInfo.CoordPoints,2));
        end
        
        function OrganizeMaster(app)
            app.MasterPointInfo.CoordPoints = horzcat(app.BasePointInfo.CoordPoints);
            app.MasterPointInfo.Color = horzcat(app.BasePointInfo.Color);
            if ~isempty(app.MasterPointInfo.CoordPoints)
                app.MasterPointInfo.CoordPoints = app.MasterPointInfo.CoordPoints + [6500;6500;2800];
            end
        end

        function updateplot(app)
            if ~isempty(app.MasterPointInfo.CoordPoints) == 1
                scatter(app.UIAxes1,app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(2,:),'filled')
                scatter(app.UIAxes2,app.MasterPointInfo.CoordPoints(2,:),app.MasterPointInfo.CoordPoints(3,:),'filled')
                scatter(app.UIAxes3,app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(3,:),'filled')
                if app.AutomaticButton.Value == true
                    InitiateAutoLim(app)
                    PushAxisLimits(app)
                else
                    PullAxisLimits(app)
                end
            else
                cla(app.UIAxes1)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
            end
        end

        function BaseCalculate(app)
            if app.BaseStructureCheckBox.Value == true
                BaseBuilder(app)
            else
                app.BasePointInfo.CoordPoints = [];
                app.BasePointInfo.Color = [];
            end
        end

        function TestOrCreateDirectory(app)
            if 7 ~= exist(app.FileFolderNameStringEditField.Value,'dir')
                mkdir(app.FileFolderNameStringEditField.Value)
            end
        end

        function SaveMasterPointInfo(app)
            MasterPointInfoTemp = app.MasterPointInfo;
            save(strcat(app.FileFolderNameStringEditField.Value,'.mat'),'MasterPointInfoTemp');
        end
        
        function GenerateXML(app)
            xmlstr = '<?xml version="1.0" encoding="ISO-8859-1"?><SimulatedExperiments file="C:/Users/ayush/Desktop/two_points.xml" modified="Mon Jul 23 13:30:09 2018&#10;" created="Mon Jul 23 13:30:09 2018&#10;"><Version major="2" minor="3" revision="0"/><AFMSimulation pixelSize="10.000000" imageWidth="300" imageHeight="300" clipGroundPlane="false" displayAsWireframe="false" surfaceOpacity="1.000000"/><FluorescenceSimulation focalPlaneIndex="14" focalPlaneSpacing="200.000000" numberOfFocalPlanes="30" useCustomFocalPlanePositions="false" gain="6870.1672448" offset="0.000000" maximumVoxelIntensity="200.000000" pixelSize="65.000000" psfName="Gibson-Lanni Widefield" imageWidth="200" imageHeight="200" shearInX="0.000000" shearInY="0.000000" addGaussianNoise="true" noiseStdDev="20.000000" showImageVolumeOutline="false" showRefGrid="true" refGridSpacing="1000.000000" superimposeSimulatedImage="true" superimposeComparisonImage="false" minimumIntensityLevel="0.000000" maximumIntensityLevel="200.000015"><FocalPlanes><Plane index="0" position="0.000000"/><Plane index="1" position="0.000000"/><Plane index="2" position="0.000000"/><Plane index="3" position="0.000000"/><Plane index="4" position="0.000000"/><Plane index="5" position="0.000000"/><Plane index="6" position="0.000000"/><Plane index="7" position="0.000000"/><Plane index="8" position="0.000000"/><Plane index="9" position="0.000000"/><Plane index="10" position="0.000000"/><Plane index="11" position="0.000000"/><Plane index="12" position="0.000000"/><Plane index="13" position="0.000000"/><Plane index="14" position="0.000000"/><Plane index="15" position="0.000000"/><Plane index="16" position="0.000000"/><Plane index="17" position="0.000000"/><Plane index="18" position="0.000000"/><Plane index="19" position="0.000000"/><Plane index="20" position="0.000000"/><Plane index="21" position="0.000000"/><Plane index="22" position="0.000000"/><Plane index="23" position="0.000000"/><Plane index="24" position="0.000000"/><Plane index="25" position="0.000000"/><Plane index="26" position="0.000000"/><Plane index="27" position="0.000000"/><Plane index="28" position="0.000000"/><Plane index="29" position="0.000000"/></FocalPlanes><GradientDescentFluorescenceOptimizer><Iterations value="100"/><DerivativeEstimateStepSize value="1e-008"/><StepScaleFactor value="1"/><ObjectiveFunction name="Gaussian Noise Maximum Likelihood"/></GradientDescentFluorescenceOptimizer><NelderMeadFluorescenceOptimizer><MaximumIterations value="100"/><ParametersConvergenceTolerance value="1e-008"/><ObjectiveFunction name="Gaussian Noise Maximum Likelihood"/></NelderMeadFluorescenceOptimizer><PointsGradientFluorescenceOptimizer><StepSize value="1"/><Iterations value="100"/><ObjectiveFunction name=""/></PointsGradientFluorescenceOptimizer><FluorescenceComparisonImageModelObject name="None"/></FluorescenceSimulation><ModelObjectList>';
            for pointidx = 1:size(app.MasterPointInfo.CoordPoints,2)
                if app.MasterPointInfo.Color(pointidx) == "Green"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="green" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPoints(1, pointidx), app.MasterPointInfo.CoordPoints(2, pointidx), app.MasterPointInfo.CoordPoints(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "Blue"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="blue" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPoints(1, pointidx), app.MasterPointInfo.CoordPoints(2, pointidx), app.MasterPointInfo.CoordPoints(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "Red"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="red" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPoints(1, pointidx), app.MasterPointInfo.CoordPoints(2, pointidx), app.MasterPointInfo.CoordPoints(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "All"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="all" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPoints(1, pointidx), app.MasterPointInfo.CoordPoints(2, pointidx), app.MasterPointInfo.CoordPoints(3, pointidx)));
                end
            end
            xmlstr = strcat(xmlstr, '</ModelObjectList></SimulatedExperiments>');
            xmlfile = fopen(strcat(app.FileFolderNameStringEditField.Value,'.xml'),'w');
            fprintf(xmlfile,xmlstr);
            fclose all;
        end
        
        function GenerateTIF(app)
            strtemp = strcat({'python XMLtoTIF.py -norename -out'},{' '},{app.FileFolderNameStringEditField.Value},{' '},{app.FileFolderNameStringEditField.Value});
            system(strtemp{1}); 
        end
        
        function BaseParametersCheck(app)
            if app.BaseStructureCheckBox.Value == false
                app.NumberofComplexesSwitchLabel.Enable = 'off';
                app.NumberofComplexesSwitch.Enable = 'off';
                app.LengthnmSpinnerLabel.Enable = 'off';
                app.LengthnmSpinner.Enable = 'off';
                app.TubuleDiameternmSpinnerLabel.Enable = 'off';
                app.TubuleDiameternmSpinner.Enable = 'off';
                app.StructureDiameternmSpinnerLabel.Enable = 'off';
                app.StructureDiameternmSpinner.Enable = 'off';
                app.NumberofFluorophoreSpinnerLabel.Enable = 'off';
                app.NumberofFluorophoreSpinner.Enable = 'off';
                app.ColorChannelDropDownLabel.Enable = 'off';
                app.ColorChannelDropDown.Enable = 'off';
                app.FixedRotationButton.Enable = 'off';
                app.RandomRotationButton.Enable = 'off';
                app.RotationCheckBox.Enable = 'off';
                app.XSpinnerLabel.Enable = 'off';
                app.XSpinner.Enable = 'off';
                app.YSpinnerLabel.Enable = 'off';
                app.YSpinner.Enable = 'off';
                app.ZSpinnerLabel.Enable = 'off';
                app.ZSpinner.Enable = 'off';
                app.LowLimSpinnerLabel.Enable = 'off';
                app.LowLimSpinner.Enable = 'off';
                app.UpLimSpinnerLabel.Enable = 'off';
                app.UpLimSpinner.Enable = 'off';
                app.NumberofChromosomesSpinnerLabel.Enable = 'off';
                app.NumberofChromosomesSpinner.Enable = 'off';
                app.StaggerDropDownLabel.Enable = 'off';
                app.StaggerDropDown.Enable = 'off';
                app.RangenmSpinnerLabel.Enable = 'off';
                app.RangenmSpinner.Enable = 'off';
            else
                app.NumberofComplexesSwitchLabel.Enable = 'on';
                app.NumberofComplexesSwitch.Enable = 'on';
                app.LengthnmSpinnerLabel.Enable = 'on';
                app.LengthnmSpinner.Enable = 'on';
                app.TubuleDiameternmSpinnerLabel.Enable = 'on';
                app.TubuleDiameternmSpinner.Enable = 'on';
                app.StructureDiameternmSpinnerLabel.Enable = 'on';
                app.StructureDiameternmSpinner.Enable = 'on';
                app.NumberofFluorophoreSpinnerLabel.Enable = 'on';
                app.NumberofFluorophoreSpinner.Enable = 'on';
                app.ColorChannelDropDownLabel.Enable = 'on';
                app.ColorChannelDropDown.Enable = 'on';
                app.RotationCheckBox.Enable = 'on';
                app.NumberofChromosomesSpinnerLabel.Enable = 'on';
                app.NumberofChromosomesSpinner.Enable = 'on';
                app.StaggerDropDownLabel.Enable = 'on';
                app.StaggerDropDown.Enable = 'on';
                if app.StaggerDropDown.Enable == 'on' & strcmp(app.StaggerDropDown.Value,'Random')
                    app.RangenmSpinnerLabel.Enable = 'on';
                    app.RangenmSpinner.Enable = 'on';
                else
                    app.RangenmSpinnerLabel.Enable = 'off';
                    app.RangenmSpinner.Enable = 'off';
                end
                if app.RotationCheckBox.Value == false
                    app.FixedRotationButton.Enable = 'off';
                    app.RandomRotationButton.Enable = 'off';
                    app.XSpinnerLabel.Enable = 'off';
                    app.XSpinner.Enable = 'off';
                    app.YSpinnerLabel.Enable = 'off';
                    app.YSpinner.Enable = 'off';
                    app.ZSpinnerLabel.Enable = 'off';
                    app.ZSpinner.Enable = 'off';
                    app.LowLimSpinnerLabel.Enable = 'off';
                    app.LowLimSpinner.Enable = 'off';
                    app.UpLimSpinnerLabel.Enable = 'off';
                    app.UpLimSpinner.Enable = 'off';
                else
                    app.FixedRotationButton.Enable = 'on';
                    app.RandomRotationButton.Enable = 'on';
                    if app.FixedRotationButton.Value == false
                        app.XSpinnerLabel.Enable = 'off';
                        app.XSpinner.Enable = 'off';
                        app.YSpinnerLabel.Enable = 'off';
                        app.YSpinner.Enable = 'off';
                        app.ZSpinnerLabel.Enable = 'off';
                        app.ZSpinner.Enable = 'off';
                        app.LowLimSpinnerLabel.Enable = 'on';
                        app.LowLimSpinner.Enable = 'on';
                        app.UpLimSpinnerLabel.Enable = 'on';
                        app.UpLimSpinner.Enable = 'on';
                    else
                        app.XSpinnerLabel.Enable = 'on';
                        app.XSpinner.Enable = 'on';
                        app.YSpinnerLabel.Enable = 'on';
                        app.YSpinner.Enable = 'on';
                        app.ZSpinnerLabel.Enable = 'on';
                        app.ZSpinner.Enable = 'on';
                        app.LowLimSpinnerLabel.Enable = 'off';
                        app.LowLimSpinner.Enable = 'off';
                        app.UpLimSpinnerLabel.Enable = 'off';
                        app.UpLimSpinner.Enable = 'off';
                    end
                end
            end
            if app.FixedRotationButton.Value == false
                app.XSpinnerLabel.Visible = 'off';
                app.XSpinner.Visible = 'off';
                app.YSpinnerLabel.Visible = 'off';
                app.YSpinner.Visible = 'off';
                app.ZSpinnerLabel.Visible = 'off';
                app.ZSpinner.Visible = 'off';
                app.LowLimSpinnerLabel.Visible = 'on';
                app.LowLimSpinner.Visible = 'on';
                app.UpLimSpinnerLabel.Visible = 'on';
                app.UpLimSpinner.Visible = 'on';
            else
                app.XSpinnerLabel.Visible = 'on';
                app.XSpinner.Visible = 'on';
                app.YSpinnerLabel.Visible = 'on';
                app.YSpinner.Visible = 'on';
                app.ZSpinnerLabel.Visible = 'on';
                app.ZSpinner.Visible = 'on';
                app.LowLimSpinnerLabel.Visible = 'off';
                app.LowLimSpinner.Visible = 'off';
                app.UpLimSpinnerLabel.Visible = 'off';
                app.UpLimSpinner.Visible = 'off';
            end                     
        end    

        function AxisParameterCheck(app)
            if app.AutomaticButton.Value == true
                app.YLowerLimnmEditFieldLabel.Enable = 'off';
                app.YLowerLimnmEditField1.Enable = 'off';
                app.YUpperLimnmEditFieldLabel.Enable = 'off';
                app.YUpperLimnmEditField1.Enable = 'off';
                app.XLowerLimnmEditFieldLabel.Enable = 'off';
                app.XLowerLimnmEditField1.Enable = 'off';
                app.XUpperLimnmEditFieldLabel.Enable = 'off';
                app.XUpperLimnmEditField1.Enable = 'off';
                app.YLowerLimnmEditField_2Label.Enable = 'off';
                app.YLowerLimnmEditField2.Enable = 'off';
                app.YUpperLimnmEditField_2Label.Enable = 'off';
                app.YUpperLimnmEditField2.Enable = 'off';
                app.XLowerLimnmEditField_2Label.Enable = 'off';
                app.XLowerLimnmEditField2.Enable = 'off';
                app.XUpperLimnmEditField_2Label.Enable = 'off';
                app.XUpperLimnmEditField2.Enable = 'off';
                app.YLowerLimnmEditField_3Label.Enable = 'off';
                app.YLowerLimnmEditField3.Enable = 'off';
                app.YUpperLimnmEditField_3Label.Enable = 'off';
                app.YUpperLimnmEditField3.Enable = 'off';
                app.XLowerLimnmEditField_3Label.Enable = 'off';
                app.XLowerLimnmEditField3.Enable = 'off';
                app.XUpperLimnmEditField_3Label.Enable = 'off';
                app.XUpperLimnmEditField3.Enable = 'off';
                app.YLowerLimnmEditFieldLabel.Visible = 'off';
                app.YLowerLimnmEditField1.Visible = 'off';
                app.YUpperLimnmEditFieldLabel.Visible = 'off';
                app.YUpperLimnmEditField1.Visible = 'off';
                app.XLowerLimnmEditFieldLabel.Visible = 'off';
                app.XLowerLimnmEditField1.Visible = 'off';
                app.XUpperLimnmEditFieldLabel.Visible = 'off';
                app.XUpperLimnmEditField1.Visible = 'off';
                app.YLowerLimnmEditField_2Label.Visible = 'off';
                app.YLowerLimnmEditField2.Visible = 'off';
                app.YUpperLimnmEditField_2Label.Visible = 'off';
                app.YUpperLimnmEditField2.Visible = 'off';
                app.XLowerLimnmEditField_2Label.Visible = 'off';
                app.XLowerLimnmEditField2.Visible = 'off';
                app.XUpperLimnmEditField_2Label.Visible = 'off';
                app.XUpperLimnmEditField2.Visible = 'off';
                app.YLowerLimnmEditField_3Label.Visible = 'off';
                app.YLowerLimnmEditField3.Visible = 'off';
                app.YUpperLimnmEditField_3Label.Visible = 'off';
                app.YUpperLimnmEditField3.Visible = 'off';
                app.XLowerLimnmEditField_3Label.Visible = 'off';
                app.XLowerLimnmEditField3.Visible = 'off';
                app.XUpperLimnmEditField_3Label.Visible = 'off';
                app.XUpperLimnmEditField3.Visible = 'off';
            else
                app.YLowerLimnmEditFieldLabel.Enable = 'on';
                app.YLowerLimnmEditField1.Enable = 'on';
                app.YUpperLimnmEditFieldLabel.Enable = 'on';
                app.YUpperLimnmEditField1.Enable = 'on';
                app.XLowerLimnmEditFieldLabel.Enable = 'on';
                app.XLowerLimnmEditField1.Enable = 'on';
                app.XUpperLimnmEditFieldLabel.Enable = 'on';
                app.XUpperLimnmEditField1.Enable = 'on';
                app.YLowerLimnmEditField_2Label.Enable = 'on';
                app.YLowerLimnmEditField2.Enable = 'on';
                app.YUpperLimnmEditField_2Label.Enable = 'on';
                app.YUpperLimnmEditField2.Enable = 'on';
                app.XLowerLimnmEditField_2Label.Enable = 'on';
                app.XLowerLimnmEditField2.Enable = 'on';
                app.XUpperLimnmEditField_2Label.Enable = 'on';
                app.XUpperLimnmEditField2.Enable = 'on';
                app.YLowerLimnmEditField_3Label.Enable = 'on';
                app.YLowerLimnmEditField3.Enable = 'on';
                app.YUpperLimnmEditField_3Label.Enable = 'on';
                app.YUpperLimnmEditField3.Enable = 'on';
                app.XLowerLimnmEditField_3Label.Enable = 'on';
                app.XLowerLimnmEditField3.Enable = 'on';
                app.XUpperLimnmEditField_3Label.Enable = 'on';
                app.XUpperLimnmEditField3.Enable = 'on';
                app.YLowerLimnmEditFieldLabel.Visible = 'on';
                app.YLowerLimnmEditField1.Visible = 'on';
                app.YUpperLimnmEditFieldLabel.Visible = 'on';
                app.YUpperLimnmEditField1.Visible = 'on';
                app.XLowerLimnmEditFieldLabel.Visible = 'on';
                app.XLowerLimnmEditField1.Visible = 'on';
                app.XUpperLimnmEditFieldLabel.Visible = 'on';
                app.XUpperLimnmEditField1.Visible = 'on';
                app.YLowerLimnmEditField_2Label.Visible = 'on';
                app.YLowerLimnmEditField2.Visible = 'on';
                app.YUpperLimnmEditField_2Label.Visible = 'on';
                app.YUpperLimnmEditField2.Visible = 'on';
                app.XLowerLimnmEditField_2Label.Visible = 'on';
                app.XLowerLimnmEditField2.Visible = 'on';
                app.XUpperLimnmEditField_2Label.Visible = 'on';
                app.XUpperLimnmEditField2.Visible = 'on';
                app.YLowerLimnmEditField_3Label.Visible = 'on';
                app.YLowerLimnmEditField3.Visible = 'on';
                app.YUpperLimnmEditField_3Label.Visible = 'on';
                app.YUpperLimnmEditField3.Visible = 'on';
                app.XLowerLimnmEditField_3Label.Visible = 'on';
                app.XLowerLimnmEditField3.Visible = 'on';
                app.XUpperLimnmEditField_3Label.Visible = 'on';
                app.XUpperLimnmEditField3.Visible = 'on';
            end
        end
        
        function PushAxisLimits(app)
            y1 = app.UIAxes1.YLim;
            x1 = app.UIAxes1.XLim;
            y2 = app.UIAxes2.YLim;
            x2 = app.UIAxes2.XLim;
            y3 = app.UIAxes3.YLim;
            x3 = app.UIAxes3.XLim;
            app.YLowerLimnmEditField1.Value = y1(1);
            app.YUpperLimnmEditField1.Value = y1(2);
            app.XLowerLimnmEditField1.Value = x1(1);
            app.XUpperLimnmEditField1.Value = x1(2)';
            app.YLowerLimnmEditField2.Value = y2(1);
            app.YUpperLimnmEditField2.Value = y2(2);
            app.XLowerLimnmEditField2.Value = x2(1);
            app.XUpperLimnmEditField2.Value = x2(2);
            app.YLowerLimnmEditField3.Value = y3(1);
            app.YUpperLimnmEditField3.Value = y3(2);
            app.XLowerLimnmEditField3.Value = x3(1);
            app.XUpperLimnmEditField3.Value = x3(2);
        end
        
        function PullAxisLimits(app)
            app.UIAxes1.YLim = [app.YLowerLimnmEditField1.Value, app.YUpperLimnmEditField1.Value];
            app.UIAxes1.XLim = [app.XLowerLimnmEditField1.Value, app.XUpperLimnmEditField1.Value];
            app.UIAxes2.YLim = [app.YLowerLimnmEditField2.Value, app.YUpperLimnmEditField2.Value];
            app.UIAxes2.XLim = [app.XLowerLimnmEditField2.Value, app.XUpperLimnmEditField2.Value];
            app.UIAxes3.YLim = [app.YLowerLimnmEditField3.Value, app.YUpperLimnmEditField3.Value];
            app.UIAxes3.XLim = [app.XLowerLimnmEditField3.Value, app.XUpperLimnmEditField3.Value];           
        end
        
        function InitiateAutoLim(app)
            app.UIAxes1.XLimMode = 'auto';
            app.UIAxes1.YLimMode = 'auto';
            app.UIAxes2.XLimMode = 'auto';
            app.UIAxes2.YLimMode = 'auto';
            app.UIAxes3.XLimMode = 'auto';
            app.UIAxes3.YLimMode = 'auto';
        end
        
        function GenerateSimuLinkData(app)
                sim('Coupled_Model_crosslinked_intext.slx')
                app.LengthSim = kMtsOut(500:end,:);
        end
        
        function SaveBioDistanceData(app)
            app.BioDistances = [-192,-1344,-640,-256,-576,-192,-768,192,-448,-1920,-512,-576,-960,-832,-128,-896,-512,-256,-704,-704,-256,-512,-256,-832,-1216,-64,-576,-768,-1024,-256,-768,-256,0,-960,-1088,-640,-832,-1024,-768,-1088,-704,-1216,-1408,-832,-896,-704,-320,-896,-512,-512,-1024,-832,-448,-256,-640,-256,-768,-576,192,-192,-384,-640,-768,-960,-768,64,-320,-704,-448,-192,-128,64,-640,-640,-448,-832,-832,-832,-960,-384,-128,-1152,-128,-256,-256,-384,-576,-896,-576,-768,-896,-640,-1024,-832,-896,-704,-320,-896,-512,-512,-192,-768,-768,-512,-960,-576,-448,-1728,-1088,128,-704,-256,-256,-832,-64,-1728,-192,-640,-1088,-832,-960,-704,-256,-1024,-960,-1152,-768,-1024,-384,-64,-512,-512,64,-640,-832,-512,-576,-832,-320,192,64,-1664,-832,-768,-384,-1152,-896,-768,64,-768,-448,-576,-192,0,-640,-768,-704,-256,-192,64,-192,256,-1152,-832,-640,-576,-576,-128,-896,-384,0,-320,-320,-448,-320,-128,-896,-1280,-448,-512,-448,-896,-832,-576,-1152,-320,-832,-256,-576,-512,-128,-704,-320,-832,-320,192,64,-1664,-832,-768,-384,-1152,-896,-768,64,-768,-448,-576,-192,0,-640,-768,0,-1024,-896,-1344,-1600,-256,128,-1216,-704,128,-1216,-960,-768,-832,-832,-320,-640,-640,0,-704,64,-896,-320,-768,-1024,-1216,-768,-448,-832,-960,-960,128,-960,-704,-256,-256,-768,-576,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-1152,0,-320,-320,-448,-704,-704,-512,-1408,-1088,-576,-128,0,-1024,128,-1152,-192,-320,-576,-384,-128,64,-704,-1024,-384,-320,-1024,-832,-448,-1152,-640,-832,-704,-192,-832,128,-768,-576,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-576,-768,-576,-256,-320,-512,-448,-128,-1344,-960,-1024,-128,-832,-1024,-704,-384,-1728,-832,-640,-1024,-1152,-256,-256,-448,-512,-768,-448,-512,-1600,-1216,-448,-512,-768,-768,-320,-448,-896,192,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-1216,-704,-576,-512,-768,-1088,-256,-1152,-384,-384,-896,-832,-576,-448,-576,-640,128,-896,-384,-512,-704,-960,-960,-1152,-384,-576,-960,-320,-832,-960,-960,-704,-1024,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,128,-1152,-128,-1024,-128,-832,-192,-256,320,-448,-1344,-832,-1216,-512,-768,64,-576,-960,-896,-512,-1024,192,-1280,-192,-832,64,-768,-960,-320,-832,-960,-960,-704,-1024,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-1024,-768,-960,64,-1024,-320,128,-256,-512,-192,64,-192,-320,-448,-896,0,-1216,-960,-768,-576,-576,-192,-1216,-512,-1280,-384,-704,64,-512,-512,-704,-384,-896,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-960,192,-128,-1152,-640,-448,-384,-192,-640,-64,-704,-1024,-704,-640,-1216,-704,-512,-256,-512,-640,-256,-704,-256,-832,-704,-576,-256,-704,64,-512,-512,-704,-384,-896,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-192,-448,128,-192,-192,-384,-192,-576,-1280,-448,-1280,-384,-576,-960,-320,-128,-64,-896,-896,-512,-384,0,-192,-640,-640,-384,-384,-320,-640,-128,-960,-960,-576,-384,-320,-448,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-832,-384,-320,64,-960,-1024,256,-832,-768,-320,-768,-576,-64,0,-448,-960,-1088,-384,-192,-640,-256,-1472,-1216,-640,-192,-448,-512,-256,-448,-768,-960,-384,-448,-320,-576,-960,-1024,-320,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,128,-832,-384,-256,-1024,-384,-640,-256,-832,64,-896,64,-640,-320,-704,-320,-1024,-576,-960,0,-1088,-832,-192,-1088,-1024,-512,-640,-832,-256,-960,-512,-640,-384,-704,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-1088,-384,-1088,-192,-512,-960,-320,-960,-704,-512,-960,-64,-320,-128,-704,-640,-1152,-256,-576,-768,-512,-256,-1152,-704,-896,-512,-384,-576,128,128,-704,-384,-704,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-576,-1024,-704,-768,-768,-640,-448,-1280,-1024,-704,-896,-1728,-256,-512,-1024,-64,-384,-128,-1024,-576,-832,-128,-320,-832,-640,64,-256,-384,-192,-768,-704,-960,-64,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-640,-640,-448,-128,-704,-448,-768,-1216,-448,-832,-192,-832,-384,-832,-768,-704,-448,-640,128,64,-448,-640,-768,-64,-704,-1280,-896,-192,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-768,-448,-384,-1152,192,-896,-384,-960,-384,-704,-512,-512,-192,-128,-832,-448,-1024,-256,-128,-576,-256,-192,-192,-832,-192,-1216,-1280,-896,-192,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-1152,-832,-640,-256,-1088,-512,-384,-1280,-320,-512,-256,-384,-1280,-1024,-896,-1216,-640,-640,-640,-1024,128,-256,-1856,-128,-1088,-512,-448,-576,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-960,-512,-192,128,-384,-960,-256,-128,-448,-640,-576,-960,-576,-896,-1024,-512,128,-960,-640,-640,-896,-512,-768,-128,-256,-896,-320,-448,-576,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-192,-128,-1024,-1344,-896,-320,-576,-768,-384,-832,-576,-128,-320,-512,-896,-1600,-256,-256,-448,-256,-704,-1216,-1280,128,-1088,-704,-1088,-512,-256,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-704,-576,-1280,-1536,-448,-768,-1344,-192,-768,-896,-448,-1088,-768,-256,-1408,-704,-1088,-640,-640,-640,-1152,-1216,-1152,-768,-128,-576,-192,-512,-320,-640,-576,-832,192,-512,-832,-832,64,-448,-1088,-256,-256,-1344,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-832,-512,-384,-704,-256,-704,-768,-768,-384,-1344,-832,-64,-704,-256,-576,-768,0,-832,-1152,-768,-704,-704,-768,-1024,-960,-128,-960,-192,-512,-320,-640,-576,-832,192,-512,-832,-832,64,-448,-1088,-256,-256,-1344,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768;-960,-192,-384,1536,-384,-960,640,-1280,-448,-128,576,-768,1216,128,-1024,448,-384,-1344,-64,-640,-768,832,-832,512,704,-704,0,-896,-448,640,256,576,-384,64,-64,640,256,-1088,64,-64,1216,-768,-192,512,704,-1280,704,384,576,1088,832,-896,768,576,0,-576,704,-64,-640,-960,1024,-640,384,-384,-64,-1280,-1088,512,-384,-512,-896,-960,-1024,448,832,0,0,576,64,-1344,832,-256,-512,512,-1408,-1408,576,448,1024,384,128,-576,-576,512,704,-1280,704,384,576,1088,1216,-192,320,0,-640,512,960,256,768,-576,1088,1280,-960,1280,-1024,-64,704,-448,128,384,448,320,-1088,64,832,128,640,512,640,-640,704,-128,-960,320,-640,-704,-512,-256,896,-1024,-576,704,-448,448,-768,-192,-448,832,-640,256,-1024,-320,-448,-768,256,832,-1152,-704,-640,-832,-768,-1408,1088,448,384,-704,0,704,-704,576,-576,640,1024,-512,768,-1088,576,-576,960,384,448,-192,320,-704,-384,448,64,-1088,320,256,448,192,704,-256,896,-1024,-576,704,-448,448,-768,-192,-448,832,-640,256,-1024,-320,-448,-768,256,832,-896,-448,448,448,512,960,-768,-256,704,-1024,64,-64,-512,448,512,448,0,-1152,-768,-384,-640,704,-768,-448,-256,-128,960,1088,320,64,-256,-832,576,64,448,640,-576,832,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-704,-704,-1088,704,-192,-1408,-704,-576,-128,-64,-448,-960,-896,-512,-1152,192,576,832,256,-320,-448,-448,960,64,1024,1088,-832,-1216,-448,-448,768,-192,1088,-448,-64,-576,-576,832,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,256,-832,960,-640,1152,1088,576,-1280,-256,64,-128,448,-832,-512,320,1408,0,-576,320,320,-512,768,-1216,-704,-768,-576,1408,704,-64,-256,1216,768,-640,576,-896,-128,-1152,-960,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-1216,64,-448,-384,960,128,64,704,-1216,1344,832,-64,64,-1152,-512,0,384,-1280,-320,-1088,-1024,-384,768,256,-128,448,320,64,-384,-1024,-512,-256,320,-256,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-448,-448,-1280,-64,1024,1088,-576,768,-960,-1088,-192,64,-256,768,384,-1216,384,384,-960,576,-64,-768,-256,-448,-320,-960,704,64,-384,-1024,-512,-256,320,-256,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-320,64,-576,-128,-768,-576,1536,-1088,-1088,640,576,-896,-576,-1024,768,-256,-896,384,0,-128,-192,1024,-896,128,448,-1024,-512,128,-576,-256,-512,192,1344,832,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,-896,960,960,1280,-832,704,704,256,-640,512,192,960,768,-704,-704,-896,-1216,-576,-704,-1024,64,-576,960,576,-896,-832,128,-576,-256,-512,192,1344,832,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-960,960,-384,-448,-704,576,448,-768,128,192,384,320,-576,1024,-64,1024,-1024,-576,384,-448,-640,896,-704,-640,-1088,256,-704,512,576,832,384,-448,384,-192,576,1472,384,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,512,-448,448,-512,64,704,-1216,-1088,640,-512,-768,448,-704,768,1152,-320,-448,576,-384,576,256,896,-768,-384,576,-640,-64,-1024,704,-768,256,-896,-512,960,-448,-960,-832,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-1024,768,1152,896,-192,-640,0,-704,-448,-640,256,-896,-704,-768,-576,-832,-576,-192,-320,-640,384,-576,832,576,192,1280,640,128,-960,-576,-1152,-832,-896,-1152,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,640,-832,448,-832,-768,1024,960,-448,-512,-832,-512,1088,-896,-1344,-1024,576,960,-448,-960,1536,-832,-576,-960,192,640,384,-320,704,-320,-1152,-960,448,-896,-1152,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-640,576,-512,-832,-384,1280,-704,0,-448,-128,1280,-512,-1088,-1664,-640,-896,1088,-640,-320,-832,-512,960,-1920,-64,-384,-1088,-768,896,832,-448,-640,-640,-576,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,0,832,1280,-448,-704,192,640,128,320,-384,576,640,832,-576,-1024,512,256,-1088,576,-896,-832,-1088,1088,-64,-704,-896,-1600,320,-1088,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,512,-512,64,-832,-768,-512,640,1216,960,832,-640,384,1024,-896,-128,1024,576,448,576,-768,-768,640,832,-128,-832,256,-1600,320,-1088,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-640,-192,512,1408,576,64,320,-320,384,640,-192,-576,192,256,320,-768,-576,-256,704,-192,256,-960,1024,640,-768,-1152,320,-896,-576,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,1152,-576,-1088,1024,192,-512,-896,640,-256,-448,-256,384,-320,-576,960,-960,0,64,-832,-192,-768,-384,-448,-1024,192,-896,-896,-576,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,576,-1408,128,320,448,-704,-832,-256,896,128,-512,-832,704,-320,-320,-192,1088,-1664,640,1408,256,128,576,-640,576,-64,832,448,768,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-896,320,832,-256,896,576,-832,384,-832,1280,576,768,-1152,-384,-576,64,-448,192,-256,-192,576,384,704,320,256,832,704,704,960,832,512,512,-192,-1088,384,64,768,-1088,-576,768,-704,384,512,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-448,704,-1088,896,-768,-1216,-384,448,-704,64,640,-704,-704,1280,576,-512,-960,-448,128,0,64,192,192,1408,-1600,1216,-1216,704,960,832,512,512,-192,-1088,384,64,768,-1088,-576,768,-704,384,512,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832;0,-300,0,0,0,0,-1200,-300,-900,-300,0,0,-300,300,0,600,0,300,-300,0,0,0,0,0,0,0,600,0,-600,0,0,300,600,300,0,600,0,-300,300,0,-300,0,0,-900,-300,-600,600,-600,0,-300,0,900,-300,0,0,0,300,0,-300,300,0,600,0,0,0,300,600,-600,300,-600,-300,300,300,600,-1200,0,300,0,0,900,-300,0,0,-900,0,300,-300,-300,-300,0,600,-300,0,-900,-300,-600,600,-600,0,-300,300,0,0,0,0,0,0,0,300,0,-300,-900,0,-300,0,-600,300,600,300,0,300,0,300,-300,300,300,600,0,-600,-600,-300,0,0,300,0,-900,600,0,-600,-900,600,600,-600,0,0,-300,0,-600,0,-300,0,300,-600,0,-600,-300,-300,0,0,0,0,300,-300,0,300,-600,0,0,0,0,0,0,0,600,-300,0,300,-300,300,300,300,0,300,0,600,600,600,-600,600,0,-300,-300,0,0,-600,-900,600,600,-600,0,0,-300,0,-600,0,-300,0,300,-600,0,-600,-300,300,0,0,-900,0,0,600,0,0,-600,0,0,300,0,-600,300,300,-600,0,0,-300,-300,0,0,0,600,0,-1800,0,300,300,0,0,0,-300,0,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,300,900,0,300,0,300,-600,0,-900,-300,0,0,0,-300,0,-300,-300,300,-900,600,0,-300,0,0,-900,600,-300,300,-300,300,-300,300,300,-300,-300,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,900,-600,0,300,0,0,300,300,300,-300,600,300,-300,300,0,-300,300,300,0,0,-300,0,300,-300,-300,0,0,300,300,-300,600,-300,300,-300,0,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-900,600,-600,0,0,-600,0,0,0,-300,0,0,0,300,0,0,0,-600,300,0,-600,-300,-600,300,-300,0,600,600,300,1200,-300,0,-900,-600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,-300,-300,-300,-300,0,-300,0,300,0,0,0,0,300,300,0,-300,600,-300,300,0,600,300,0,0,0,0,600,300,1200,-300,0,-900,-600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-900,0,300,0,0,0,0,0,-300,300,-300,0,900,-1200,-300,-300,0,0,600,900,300,300,300,-600,-300,0,-900,0,0,-300,-600,300,600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,0,-300,0,0,0,300,-300,0,0,0,0,600,300,-300,0,-600,0,-900,-300,-600,-300,0,-300,0,-300,-300,-900,0,0,-300,-600,300,600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-600,0,300,-600,0,300,900,-600,-600,-300,0,0,300,0,-900,0,0,0,-600,0,-300,0,0,0,-300,-600,300,0,0,-300,0,600,-600,-600,0,600,0,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,600,0,600,0,900,0,300,0,0,-300,0,0,0,-900,600,-600,900,-300,-300,300,0,0,0,-300,300,300,600,0,-900,0,-300,-300,0,300,0,0,0,0,-600,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-600,0,-900,600,0,0,0,0,-300,0,-300,600,0,0,-300,300,0,300,0,0,0,-300,0,300,0,-900,1500,-300,0,600,-300,300,0,300,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-600,-300,-300,600,600,0,-300,-300,0,0,0,0,-300,0,-300,0,-300,0,0,-300,-300,-600,-900,-600,-600,0,-300,0,600,300,0,0,300,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-300,-600,0,300,600,300,0,0,-600,0,0,0,-600,0,300,0,-600,0,0,0,-600,600,-600,0,300,0,0,0,-300,0,0,-300,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,600,-300,300,600,-300,-1500,0,900,0,0,-900,-300,-300,0,0,0,300,300,0,-300,0,0,0,-300,-900,0,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-600,300,0,300,300,0,-300,-600,0,0,600,0,0,-300,-600,300,0,300,0,0,-300,300,0,-300,0,0,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,300,0,0,300,0,-300,0,0,600,-300,0,-300,-300,0,-900,0,-300,0,300,-600,0,0,-600,900,600,300,-600,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,0,-300,300,300,0,0,0,0,0,-300,-300,0,0,0,-600,0,0,0,0,0,300,0,0,600,-600,0,-600,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,0,-300,-300,-600,-600,0,300,-300,0,300,0,-600,300,0,-300,0,600,0,300,0,1200,-300,0,0,600,-600,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,600,-300,-300,-300,300,0,0,300,0,600,-600,0,0,-600,0,0,-600,0,300,0,0,900,-300,0,-600,600,-300,0,-300,-300,0,0,-300,600,-900,300,0,0,-300,-600,-300,300,600,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,600,300,-300,-600,300,600,0,0,0,-300,0,0,0,600,-600,900,-600,0,0,300,-300,300,-600,0,300,600,0,-300,-300,0,0,-300,600,-900,300,0,0,-300,-600,-300,300,600,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300];
        end
        
        function BaseStructure1(app)
            CoordAdjustment = zeros(3,1);
            for angle = 0:(2*pi/app.NumberofChromosomesSpinner.Value):(2*pi-2*pi/app.NumberofChromosomesSpinner.Value)
                if strcmp(app.StaggerDropDown.Value,'Random')
                    CoordAdjustment(1,1) = (rand()-0.5)*(app.RangenmSpinner.Value*2);
                end
                if strcmp(app.StaggerDropDown.Value,'SimuLink Data (Stevens et al.)') & app.NumberofChromosomesSpinner.Value <= 32
                    CoordAdjustment(1,1) = (app.LengthSim(ceil(rand()*size(app.LengthSim,1)),ceil(rand()*size(app.LengthSim,2)))-3.5e-07)*1e9;
                end
                CoordAdjustment(2,1) = cos(angle)*app.StructureDiameternmSpinner.Value/2;
                CoordAdjustment(3,1) = sin(angle)*app.StructureDiameternmSpinner.Value/2;
                CylinderCoordPoints = CylinderFluorophorePoints(app);
                app.BasePointInfo.CoordPoints = horzcat(app.BasePointInfo.CoordPoints, (CylinderCoordPoints + CoordAdjustment));
            end
        end

        function BaseStructure2(app)
            CoordAdjustment = zeros(3,1);
            correction = app.BioDistances(:,ceil(rand()*length(app.BioDistances)))/2;
            for angle = 0:(2*pi/app.NumberofChromosomesSpinner.Value):(2*pi-2*pi/app.NumberofChromosomesSpinner.Value)
                if strcmp(app.StaggerDropDown.Value,'Random')
                    CoordAdjustment(1,1) = (rand()-0.5)*(app.RangenmSpinner.Value*2);
                end
                if strcmp(app.StaggerDropDown.Value,'SimuLink Data (Stevens et al.)') & app.NumberofChromosomesSpinner.Value <= 32
                    CoordAdjustment(1,1) = (app.LengthSim(ceil(rand()*size(app.LengthSim,1)),ceil(rand()*size(app.LengthSim,2)))-3.5e-07)*1e9;
                end
                CoordAdjustment(2,1) = cos(angle)*app.StructureDiameternmSpinner.Value/2;
                CoordAdjustment(3,1) = sin(angle)*app.StructureDiameternmSpinner.Value/2;
                CylinderCoordPoints = CylinderFluorophorePoints(app);
                app.BasePointInfo.CoordPoints = horzcat(app.BasePointInfo.CoordPoints, (CylinderCoordPoints + CoordAdjustment));
            end
            app.BasePointInfo.CoordPoints = (RotationMatrixGenerate(app,correction)*app.BasePointInfo.CoordPoints) - correction;
            for angle = 0:(2*pi/app.NumberofChromosomesSpinner.Value):(2*pi-pi/app.NumberofChromosomesSpinner.Value)
                if strcmp(app.StaggerDropDown.Value,'Random')
                    CoordAdjustment(1,1) = (rand()-0.5)*(app.RangenmSpinner.Value*2);
                end
                if strcmp(app.StaggerDropDown.Value,'SimuLink Data (Stevens et al.)') & app.NumberofChromosomesSpinner.Value <= 32
                    CoordAdjustment(1,1) = (app.LengthSim(ceil(rand()*size(app.LengthSim,1)),ceil(rand()*size(app.LengthSim,2)))-3.5e-07)*1e9;
                end
                CoordAdjustment(2,1) = cos(angle)*app.StructureDiameternmSpinner.Value/2;
                CoordAdjustment(3,1) = sin(angle)*app.StructureDiameternmSpinner.Value/2;
                CylinderCoordPoints = CylinderFluorophorePoints(app);
                app.BasePointInfo.CoordPoints2 = horzcat(app.BasePointInfo.CoordPoints2, (-(CylinderCoordPoints + CoordAdjustment)));
            end
            app.BasePointInfo.CoordPoints2 = (RotationMatrixGenerate(app, correction)*app.BasePointInfo.CoordPoints2) + correction;
            app.BasePointInfo.CoordPoints = horzcat(app.BasePointInfo.CoordPoints, app.BasePointInfo.CoordPoints2);
        end
        
        function rotation = RotationMatrixGenerate(app, vector)
            ninit = [1;0;0]; 
            ninit = ninit/norm(ninit);
            nfinal = vector; 
            nfinal = nfinal/norm(nfinal); 
            c = dot(ninit,nfinal) / ( norm(ninit)*norm(nfinal) ); 
            s = sqrt(1-c*c);
            u = cross(ninit,nfinal) / ( norm(ninit)*norm(nfinal) );
            u = u/norm(u);
            C = 1-c;
            rotation = [u(1)^2*C+c, u(1)*u(2)*C-u(3)*s, u(1)*u(3)*C+u(2)*s
                u(2)*u(1)*C+u(3)*s, u(2)^2*C+c, u(2)*u(3)*C-u(1)*s
                u(3)*u(1)*C-u(2)*s, u(3)*u(2)*C+u(1)*s, u(3)^2*C+c];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AxisParameterCheck(app)
            SaveBioDistanceData(app)
            GenerateSimuLinkData(app)
            BaseParametersCheck(app)
            BaseCalculate(app)
            OrganizeMaster(app)
            updateplot(app)
        end

        % Callback function: BaseStructureCheckBox, 
        % NumberofComplexesSwitch, RotationButtonGroup, 
        % RotationCheckBox, StaggerDropDown
        function BaseStructureParameterChange(app, event)
            BaseParametersCheck(app)
            BaseCalculate(app)
            OrganizeMaster(app)
            updateplot(app)    
        end

        % Callback function: ColorChannelDropDown, LengthnmSpinner, 
        % LowLimSpinner, NumberofChromosomesSpinner, 
        % NumberofFluorophoreSpinner, RangenmSpinner, 
        % StructureDiameternmSpinner, TubuleDiameternmSpinner, 
        % UpLimSpinner, XSpinner, YSpinner, ZSpinner
        function BaseStructureValueChange(app, event)
            BaseCalculate(app);
            OrganizeMaster(app);
            updateplot(app);
        end

        % Callback function: FileFolderNameStringEditField, 
        % GenerateTIFsCheckBox, GenerateXMLCheckBox, SaveMATFileCheckBox
        function OutputParameterChange(app, event)
            if app.GenerateXMLCheckBox.Value == true
                app.GenerateTIFsCheckBox.Enable = 'on';
            else
                app.GenerateTIFsCheckBox.Enable = 'off';
            end
            if isempty(app.FileFolderNameStringEditField.Value) || (app.GenerateXMLCheckBox.Value == false && app.SaveMATFileCheckBox.Value == false)
                app.GenerateOutputButton.Enable = "off";
            else
                app.GenerateOutputButton.Enable = "on";
            end
        end

        % Button pushed function: GenerateOutputButton
        function GenerateOutputButtonPushed(app, event)
            if isempty(app.MasterPointInfo.CoordPoints) == 1
                app.StatusEditField.Value = 'No Structure Has Been Selected';
            else
                app.StatusEditField.Value = 'Saving/Writing/Taking Pictures. Please Hold.';
                app.FileFolderNameStringEditField.Value = strtrim(app.FileFolderNameStringEditField.Value);
                TestOrCreateDirectory(app)
                cd(app.FileFolderNameStringEditField.Value)
                if app.SaveMATFileCheckBox.Value == true
                    SaveMasterPointInfo(app);
                end
                if app.GenerateXMLCheckBox.Value == true
                    GenerateXML(app);
                end
                cd ..
                if app.GenerateTIFsCheckBox.Enable == 'on' & app.GenerateTIFsCheckBox.Value == true
                        GenerateTIF(app)
                end
                app.StatusEditField.Value = 'Finished!';
            end
        end

        % Button pushed function: DViewPopOutButton
        function DViewPopOutButtonPushed(app, event)
            if ~isempty(app.MasterPointInfo.CoordPoints) == 1
                figure()
                scatter3(app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(2,:),app.MasterPointInfo.CoordPoints(3,:),'filled')
                rotate3d on
            end
        end

        % Selection changed function: AxisLimitsButtonGroup
        function AxisLimitsParameterChange(app, event)
            AxisParameterCheck(app)
            updateplot(app)
        end

        % Value changed function: XLowerLimnmEditField1, 
        % XLowerLimnmEditField2, XLowerLimnmEditField3, 
        % XUpperLimnmEditField1, XUpperLimnmEditField2, 
        % XUpperLimnmEditField3, YLowerLimnmEditField1, 
        % YLowerLimnmEditField2, YLowerLimnmEditField3, 
        % YUpperLimnmEditField1, YUpperLimnmEditField2, 
        % YUpperLimnmEditField3
        function AxisLimitsValueChange(app, event)
            updateplot(app)
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            web('https://github.com/BloomLabYeast','-browser');
        end

        % Menu selected function: OtherProgramsMenu
        function OtherProgramsMenuSelected(app, event)
            web('http://bloomlab.web.unc.edu/resources/programs/','-browser');
        end

        % Menu selected function: BloomLabWebsiteMenu
        function BloomLabWebsiteMenuSelected(app, event)
            web('http://bloomlab.web.unc.edu/','-browser')
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StaticKinetUIFigure = uifigure;
            app.StaticKinetUIFigure.Name = 'Static Kinet';
            app.FileMenu = uimenu(app.StaticKinetUIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.AboutMenu = uimenu(app.StaticKinetUIFigure);
            app.AboutMenu.Text = 'About';
            app.BloomLabWebsiteMenu = uimenu(app.AboutMenu);
            app.BloomLabWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @BloomLabWebsiteMenuSelected, true);
            app.BloomLabWebsiteMenu.Separator = 'on';
            app.BloomLabWebsiteMenu.Text = 'Bloom Lab Website';
            app.OtherProgramsMenu = uimenu(app.AboutMenu);
            app.OtherProgramsMenu.MenuSelectedFcn = createCallbackFcn(app, @OtherProgramsMenuSelected, true);
            app.OtherProgramsMenu.Separator = 'on';
            app.OtherProgramsMenu.Text = 'Other Programs';
            app.GitHubMenu = uimenu(app.AboutMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Separator = 'on';
            app.GitHubMenu.Text = 'GitHub';
            app.HelpMenu = uimenu(app.StaticKinetUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Separator = 'on';
            app.DocumentationMenu.Text = 'Documentation';
            app.BaseStructurePanel = uipanel(app.StaticKinetUIFigure);
            app.BaseStructurePanel.Title = 'Base Structure';
            app.RotationButtonGroup = uibuttongroup(app.BaseStructurePanel);
            app.RotationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BaseStructureParameterChange, true);
            app.RotationButtonGroup.Title = 'Rotation';
            app.FixedRotationButton = uiradiobutton(app.RotationButtonGroup);
            app.FixedRotationButton.Enable = 'off';
            app.FixedRotationButton.Text = 'Fixed Rotation';
            app.FixedRotationButton.Value = true;
            app.RandomRotationButton = uiradiobutton(app.RotationButtonGroup);
            app.RandomRotationButton.Enable = 'off';
            app.RandomRotationButton.Text = 'Random Rotation';
            app.RotationCheckBox = uicheckbox(app.BaseStructurePanel);
            app.RotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaseStructureParameterChange, true);
            app.RotationCheckBox.Enable = 'off';
            app.RotationCheckBox.Text = '';
            app.RotationParametersdegPanel = uipanel(app.BaseStructurePanel);
            app.RotationParametersdegPanel.Title = 'Rotation Parameters (deg.)';
            app.XSpinnerLabel = uilabel(app.RotationParametersdegPanel);
            app.XSpinnerLabel.Enable = 'off';
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.RotationParametersdegPanel);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.XSpinner.Limits = [0 360];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.XSpinner.Enable = 'off';
            app.YSpinnerLabel = uilabel(app.RotationParametersdegPanel);
            app.YSpinnerLabel.Enable = 'off';
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.RotationParametersdegPanel);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.YSpinner.Limits = [0 360];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.YSpinner.Enable = 'off';
            app.ZSpinnerLabel = uilabel(app.RotationParametersdegPanel);
            app.ZSpinnerLabel.Enable = 'off';
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.RotationParametersdegPanel);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.ZSpinner.Limits = [0 360];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.ZSpinner.Enable = 'off';
            app.LowLimSpinnerLabel = uilabel(app.RotationParametersdegPanel);
            app.LowLimSpinnerLabel.Enable = 'off';
            app.LowLimSpinnerLabel.Visible = 'off';
            app.LowLimSpinnerLabel.Text = 'Low. Lim.';
            app.LowLimSpinner = uispinner(app.RotationParametersdegPanel);
            app.LowLimSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.LowLimSpinner.Limits = [0 360];
            app.LowLimSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.LowLimSpinner.Enable = 'off';
            app.UpLimSpinnerLabel = uilabel(app.RotationParametersdegPanel);
            app.UpLimSpinnerLabel.Enable = 'off';
            app.UpLimSpinnerLabel.Visible = 'off';
            app.UpLimSpinnerLabel.Text = 'Up. Lim.';
            app.UpLimSpinner = uispinner(app.RotationParametersdegPanel);
            app.UpLimSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.UpLimSpinner.Limits = [0 360];
            app.UpLimSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.UpLimSpinner.Enable = 'off';
            app.TubuleDiameternmSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.TubuleDiameternmSpinnerLabel.Enable = 'off';
            app.TubuleDiameternmSpinnerLabel.Text = 'Tubule Diameter (nm)';
            app.StructureDiameternmSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.StructureDiameternmSpinnerLabel.Enable = 'off';
            app.StructureDiameternmSpinnerLabel.Text = 'Structure Diameter (nm)';
            app.NumberofFluorophoreSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.NumberofFluorophoreSpinnerLabel.Enable = 'off';
            app.NumberofFluorophoreSpinnerLabel.Text = 'Number of Fluorophore';
            app.ColorChannelDropDownLabel = uilabel(app.BaseStructurePanel);
            app.ColorChannelDropDownLabel.Enable = 'off';
            app.ColorChannelDropDownLabel.Text = 'Color Channel';
            app.NumberofChromosomesSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.NumberofChromosomesSpinnerLabel.Enable = 'off';
            app.NumberofChromosomesSpinnerLabel.Text = 'Number of Chromosomes';
            app.LengthnmSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.LengthnmSpinnerLabel.Enable = 'off';
            app.LengthnmSpinnerLabel.Text = 'Length (nm)';
            app.LengthnmSpinner = uispinner(app.BaseStructurePanel);
            app.LengthnmSpinner.LowerLimitInclusive = 'off';
            app.LengthnmSpinner.UpperLimitInclusive = 'off';
            app.LengthnmSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.LengthnmSpinner.Limits = [0 Inf];
            app.LengthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.LengthnmSpinner.Enable = 'off';
            app.LengthnmSpinner.Value = 2;
            app.TubuleDiameternmSpinner = uispinner(app.BaseStructurePanel);
            app.TubuleDiameternmSpinner.Step = 5;
            app.TubuleDiameternmSpinner.LowerLimitInclusive = 'off';
            app.TubuleDiameternmSpinner.UpperLimitInclusive = 'off';
            app.TubuleDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.TubuleDiameternmSpinner.Limits = [0 Inf];
            app.TubuleDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.TubuleDiameternmSpinner.Enable = 'off';
            app.TubuleDiameternmSpinner.Value = 25;
            app.StructureDiameternmSpinner = uispinner(app.BaseStructurePanel);
            app.StructureDiameternmSpinner.Step = 10;
            app.StructureDiameternmSpinner.LowerLimitInclusive = 'off';
            app.StructureDiameternmSpinner.UpperLimitInclusive = 'off';
            app.StructureDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.StructureDiameternmSpinner.Limits = [0 Inf];
            app.StructureDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.StructureDiameternmSpinner.Enable = 'off';
            app.StructureDiameternmSpinner.Value = 250;
            app.NumberofFluorophoreSpinner = uispinner(app.BaseStructurePanel);
            app.NumberofFluorophoreSpinner.LowerLimitInclusive = 'off';
            app.NumberofFluorophoreSpinner.UpperLimitInclusive = 'off';
            app.NumberofFluorophoreSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.NumberofFluorophoreSpinner.Limits = [0 Inf];
            app.NumberofFluorophoreSpinner.RoundFractionalValues = 'on';
            app.NumberofFluorophoreSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.NumberofFluorophoreSpinner.Enable = 'off';
            app.NumberofFluorophoreSpinner.Value = 20;
            app.ColorChannelDropDown = uidropdown(app.BaseStructurePanel);
            app.ColorChannelDropDown.Items = {'All', 'Green', 'Red', 'Blue'};
            app.ColorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.ColorChannelDropDown.Enable = 'off';
            app.ColorChannelDropDown.Value = 'All';
            app.NumberofChromosomesSpinner = uispinner(app.BaseStructurePanel);
            app.NumberofChromosomesSpinner.LowerLimitInclusive = 'off';
            app.NumberofChromosomesSpinner.UpperLimitInclusive = 'off';
            app.NumberofChromosomesSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.NumberofChromosomesSpinner.Limits = [0 Inf];
            app.NumberofChromosomesSpinner.RoundFractionalValues = 'on';
            app.NumberofChromosomesSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.NumberofChromosomesSpinner.Enable = 'off';
            app.NumberofChromosomesSpinner.Value = 16;
            app.StaggerDropDownLabel = uilabel(app.BaseStructurePanel);
            app.StaggerDropDownLabel.Enable = 'off';
            app.StaggerDropDownLabel.Text = 'Stagger';
            app.StaggerDropDown = uidropdown(app.BaseStructurePanel);
            app.StaggerDropDown.Items = {'None', 'Random', 'SimuLink Data (Stevens et al.)'};
            app.StaggerDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseStructureParameterChange, true);
            app.StaggerDropDown.Enable = 'off';
            app.StaggerDropDown.Value = 'None';
            app.RangenmSpinnerLabel = uilabel(app.BaseStructurePanel);
            app.RangenmSpinnerLabel.Enable = 'off';
            app.RangenmSpinnerLabel.Text = 'Range +/- (nm)';
            app.RangenmSpinner = uispinner(app.BaseStructurePanel);
            app.RangenmSpinner.ValueChangingFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.RangenmSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseStructureValueChange, true);
            app.RangenmSpinner.Enable = 'off';
            app.NumberofComplexesSwitchLabel = uilabel(app.BaseStructurePanel);
            app.NumberofComplexesSwitchLabel.Enable = 'off';
            app.NumberofComplexesSwitchLabel.Text = 'Number of Complexes';
            app.NumberofComplexesSwitch = uiswitch(app.BaseStructurePanel, 'slider');
            app.NumberofComplexesSwitch.Items = {'1', '2'};
            app.NumberofComplexesSwitch.ValueChangedFcn = createCallbackFcn(app, @BaseStructureParameterChange, true);
            app.NumberofComplexesSwitch.Enable = 'off';
            app.NumberofComplexesSwitch.Value = '1';
            app.BaseStructureCheckBox = uicheckbox(app.StaticKinetUIFigure);
            app.BaseStructureCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaseStructureParameterChange, true);
            app.BaseStructureCheckBox.Text = '';
            app.OutputPanel = uipanel(app.StaticKinetUIFigure);
            app.OutputPanel.Title = 'Output';
            app.FileFolderNameStringLabel = uilabel(app.OutputPanel);
            app.FileFolderNameStringLabel.Text = 'File/Folder Name String:';
            app.FileFolderNameStringEditField = uieditfield(app.OutputPanel, 'text');
            app.FileFolderNameStringEditField.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.FileFolderNameStringEditField.ValueChangingFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateXMLCheckBox = uicheckbox(app.OutputPanel);
            app.GenerateXMLCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateXMLCheckBox.Text = 'Generate XML';
            app.SaveMATFileCheckBox = uicheckbox(app.OutputPanel);
            app.SaveMATFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.SaveMATFileCheckBox.Text = 'Save MAT File';
            app.GenerateTIFsCheckBox = uicheckbox(app.OutputPanel);
            app.GenerateTIFsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateTIFsCheckBox.Enable = 'off';
            app.GenerateTIFsCheckBox.Text = 'Generate TIFs';
            app.GenerateOutputButton = uibutton(app.OutputPanel, 'push');
            app.GenerateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOutputButtonPushed, true);
            app.GenerateOutputButton.Enable = 'off';
            app.GenerateOutputButton.Text = 'Generate Output!';
            app.StatusLabel = uilabel(app.OutputPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.OutputPanel, 'text');
            app.DViewPopOutButton = uibutton(app.StaticKinetUIFigure, 'push');
            app.DViewPopOutButton.ButtonPushedFcn = createCallbackFcn(app, @DViewPopOutButtonPushed, true);
            app.DViewPopOutButton.Text = '3D View (Pop-Out)';
            app.UIAxes2 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes2, '+Y vs. +Z')
            app.UIAxes1 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes1, '+X vs. +Y')
            app.UIAxes3 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes3, '+X vs. +Z')
            app.AxisLimitsButtonGroup = uibuttongroup(app.StaticKinetUIFigure);
            app.AxisLimitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisLimitsParameterChange, true);
            app.AxisLimitsButtonGroup.Title = 'Axis Limits';
            app.AutomaticButton = uiradiobutton(app.AxisLimitsButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.FixedButton = uiradiobutton(app.AxisLimitsButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.YLowerLimnmEditFieldLabel = uilabel(app.StaticKinetUIFigure);
            app.YLowerLimnmEditFieldLabel.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField1 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YLowerLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditFieldLabel = uilabel(app.StaticKinetUIFigure);
            app.YUpperLimnmEditFieldLabel.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField1 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YUpperLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditFieldLabel = uilabel(app.StaticKinetUIFigure);
            app.XLowerLimnmEditFieldLabel.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField1 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XLowerLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditFieldLabel = uilabel(app.StaticKinetUIFigure);
            app.XUpperLimnmEditFieldLabel.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField1 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XUpperLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YLowerLimnmEditField_2Label = uilabel(app.StaticKinetUIFigure);
            app.YLowerLimnmEditField_2Label.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField2 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YLowerLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditField_2Label = uilabel(app.StaticKinetUIFigure);
            app.YUpperLimnmEditField_2Label.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField2 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YUpperLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditField_2Label = uilabel(app.StaticKinetUIFigure);
            app.XLowerLimnmEditField_2Label.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField2 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XLowerLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditField_2Label = uilabel(app.StaticKinetUIFigure);
            app.XUpperLimnmEditField_2Label.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField2 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XUpperLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YLowerLimnmEditField_3Label = uilabel(app.StaticKinetUIFigure);
            app.YLowerLimnmEditField_3Label.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField3 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YLowerLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditField_3Label = uilabel(app.StaticKinetUIFigure);
            app.YUpperLimnmEditField_3Label.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField3 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.YUpperLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditField_3Label = uilabel(app.StaticKinetUIFigure);
            app.XLowerLimnmEditField_3Label.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField3 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XLowerLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditField_3Label = uilabel(app.StaticKinetUIFigure);
            app.XUpperLimnmEditField_3Label.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField3 = uieditfield(app.StaticKinetUIFigure, 'numeric');
            app.XUpperLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = StaticKinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StaticKinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StaticKinetUIFigure)
        end
    end
end